classdef ImageAnalyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Panel                    matlab.ui.container.Panel
        LoadimageButton          matlab.ui.control.StateButton
        Channel1CheckBox         matlab.ui.control.CheckBox
        Channel2CheckBox         matlab.ui.control.CheckBox
        Channel3CheckBox         matlab.ui.control.CheckBox
        ColocalizationButton     matlab.ui.control.Button
        ColocalizationDropDown1  matlab.ui.control.DropDown
        ColocalizationDropDown2  matlab.ui.control.DropDown
        CellMaskCheckBox         matlab.ui.control.CheckBox
        SegmentDropDown          matlab.ui.control.DropDown
        SegmentCellLabel         matlab.ui.control.Label
        LoadMaskButton           matlab.ui.control.Button
        SegmentSlider            matlab.ui.control.Slider
        WatershedButton          matlab.ui.control.Button
        LoadImageLabel           matlab.ui.control.Label
        AnalyzeLabel             matlab.ui.control.Label
        SegmentThresholdButton   matlab.ui.control.Button
        SaveLabel                matlab.ui.control.Label
        SaveMaskButton           matlab.ui.control.Button
        SaveDataButton           matlab.ui.control.Button
        LevelLabel2              matlab.ui.control.Label
        ApproximateButton        matlab.ui.control.Button
        VesiclesDropDown2        matlab.ui.control.DropDown
        ThresholdVesicles        matlab.ui.control.Button
        VesiclesDropDown         matlab.ui.control.DropDown
        VesiclesLabel            matlab.ui.control.Label
        VesiclesSlider           matlab.ui.control.Slider
        LevelLabel               matlab.ui.control.Label
        Vesicles1CheckBox        matlab.ui.control.CheckBox
        Vesicles2CheckBox        matlab.ui.control.CheckBox
        SizeEditFieldLabel       matlab.ui.control.Label
        SizeEditField            matlab.ui.control.NumericEditField
        SegmentSigma             matlab.ui.control.Slider
        LevelLabel2_2            matlab.ui.control.Label
        VesiclesSigma            matlab.ui.control.Slider
        SigmaLabel               matlab.ui.control.Label
        SegmentRadius            matlab.ui.control.Slider
        LevelLabel2_3            matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
        ZSliderLabel             matlab.ui.control.Label
        ZSlider                  matlab.ui.control.Slider
    end

    properties (Access = private)
        C1 % channel 1
        C2 % channel 2
        C3 % channel 3
        voxelSize %voxel size
        cellMask % binary image of the cell
        vesicles1 % binary image of vesicles
        vesStats1 % struct containing vesicle stats
        vesicles2 % binary image of vesicles
        vesStats2 % struct containing vesicle stats
        colocalization % colocalization value
        currentfile %current file
    end

    methods (Access = private)

        % Code that executes after component creation
        function initialize(app)
           app.C1 = [];
           app.C2 = [];
           app.C3 = [];
           app.cellMask = [];
           app.vesicles1 = [];
           app.vesicles2 = [];
        end

        % Button pushed function: ApproximateButton
        function ApproximateButtonPushed(app, event)
            if(isempty(app.C1))
                msgbox('Load image first');
            else
                app.cellMask = approximatecell(app.C1,app.SegmentRadius.Value);
                app.showImage();
            end
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            savedata(app.currentfile,app.colocalization,app.vesStats1,app.vesStats2);
        end

        % Button pushed function: SaveMaskButton
        function SaveMaskButtonPushed(app, event)
            if(isempty(app.cellMask))
                msgbox('Calculate cell mask first');
            else
                savetif1(app.cellMask,strcat('data/mask_',app.currentfile));
            end
        end

        % Button pushed function: WatershedButton
        function WatershedButtonPushed(app, event)
             if(isempty(app.cellMask))
                msgbox('Need rough cell mask');
            else
                if(app.SegmentDropDown.Value == 'Channel 1')
                    app.cellMask = watershedcell(app.C1,app.cellMask,app.SegmentSigma.Value);
                elseif(app.SegmentDropDown.Value == 'Channel 2')
                    app.cellMask = watershedcell(app.C2,app.cellMask,app.SegmentSigma.Value);
                else
                    app.cellMask =  watershedcell(app.C3,app.cellMask,app.SegmentSigma.Value);
                end
                app.showImage();
            end
        end

        % Button pushed function: SegmentThresholdButton
        function cellThresholdButtonPushed(app, event)
            if(isempty(app.C1))
                msgbox('Load image first');
            else
                if(app.SegmentDropDown.Value == 'Channel 1')
                    app.cellMask = thresholdcell(app.C1,app.SegmentSlider.Value,app.SegmentSigma.Value);
                elseif(app.SegmentDropDown.Value == 'Channel 2')
                    app.cellMask = thresholdcell(app.C2,app.SegmentSlider.Value,app.SegmentSigma.Value);
                else
                    app.cellMask = thresholdcell(app.C3,app.SegmentSlider.Value,app.SegmentSigma.Value);
                end
                app.showImage();
            end
        end

        % Button pushed function: ColocalizationButton
        function getColocalizationButtonPushed(app, event)
            if(isempty(app.cellMask))
                msgbox('Calculate cell mask first');
            else
                if(app.ColocalizationDropDown1.Value == 'Channel 1')
                    C4 = app.C1;
                elseif(app.ColocalizationDropDown1.Value == 'Channel 2')
                    C4 = app.C2;
                else
                    C4 = app.C3;
                end
                if(app.ColocalizationDropDown2.Value == 'Channel 1')
                    C5 = app.C1;
                elseif(app.ColocalizationDropDown2.Value == 'Channel 2')
                    C5 = app.C2;
                else
                    C5 = app.C3;
                end
                app.colocalization = pearson(C4,C5,app.cellMask);
                msgbox(strcat('Pearson: ',num2str(app.colocalization,5)));
            end  
        end

        % Value changed function: LoadimageButton
        function loadImageButtonPushed(app, event)
            filename = uigetfile('*.tif');
            app.currentfile = filename;
            [app.C1,app.C2,app.C3,app.voxelSize] = loadtif3(filename);
            app.ZSlider.Limits = [1 size(app.C1,3)];
            app.showImage();
            app.LoadimageButton.Value = 0;
        end

        % Button pushed function: LoadMaskButton
        function loadMaskButtonPushed(app, event)
            filename = uigetfile('*.tif');
            app.cellMask = loadtif1(filename);
        end

        % Callback function: CellMaskCheckBox, Channel1CheckBox, 
        % Channel2CheckBox, Channel3CheckBox, ZSlider
        function showImage(app, event)
            z = round(app.ZSlider.Value);
            if(app.Channel1CheckBox.Value == 1)
                R = app.C1(:,:,z);
            else
                R = app.C1(:,:,1).*0;
            end
            if(app.Channel2CheckBox.Value == 1)
                G = app.C2(:,:,z);
            else
                G = app.C2(:,:,1).*0;
            end
            if(app.Channel3CheckBox.Value == 1)
                B = app.C3(:,:,z);
            else
                B = app.C3(:,:,1).*0;
            end
            if(app.CellMaskCheckBox.Value == 1)
                if(isempty(app.cellMask))
                    msgbox('Calculate cell mask first');
                else
                    R = max(R,bwperim(app.cellMask(:,:,z)));
                    G = max(G,bwperim(app.cellMask(:,:,z)));
                    B = max(B,bwperim(app.cellMask(:,:,z)));
                end
            end
            if(app.Vesicles1CheckBox.Value == 1)
                if(isempty(app.vesicles1))
                    msgbox('Calculate vesicles 1 first');
                else
                    R = max(R,app.vesicles1(:,:,z));
                    G = max(G,app.vesicles1(:,:,z));
                    B = max(B,app.vesicles1(:,:,z));
                end
            end
            if(app.Vesicles2CheckBox.Value == 1)
                if(isempty(app.vesicles2))
                    msgbox('Calculate vesicles 2 first');
                else
                    R = max(R,app.vesicles2(:,:,z));
                    G = max(G,app.vesicles2(:,:,z));
                    B = max(B,app.vesicles2(:,:,z));
                end
            end
            image(app.UIAxes,cat(3,R,G,B));
        end

        % Callback function
        function thresholdVesiclesButtonPushed(app, event)
            if(isempty(app.cellMask))
                msgbox('Calculate cell mask first');
            msgbox(app.VesiclesSlider.Value);
            else
                if(app.VesiclesDropDown2.Value == 'Vesicles 1')
                    if(app.VesiclesDropDown.Value == 'Channel 1')
                        [app.vesicles1,app.vesStats1] = thresholdvesicles(app.C1,app.cellMask,app.VesiclesSlider.Value,app.SizeEditField.Value,2);
                    elseif(app.VesiclesDropDown.Value == 'Channel 2')
                        [app.vesicles1,app.vesStats1] = thresholdvesicles(app.C2,app.cellMask,app.VesiclesSlider.Value,app.SizeEditField.Value,2);
                    else
                        [app.vesicles1,app.vesStats1] = thresholdvesicles(app.C3,app.cellMask,app.VesiclesSlider.Value,app.SizeEditField.Value,2);
                    end
                else
                    if(app.VesiclesDropDown.Value == 'Channel 1')
                        [app.vesicles2,app.vesStats2] = thresholdvesicles(app.C1,app.cellMask,app.VesiclesSlider.Value,app.SizeEditField.Value,2);
                    elseif(app.VesiclesDropDown.Value == 'Channel 2')
                        [app.vesicles2,app.vesStats2] = thresholdvesicles(app.C2,app.cellMask,app.VesiclesSlider.Value,app.SizeEditField.Value,2);
                    else
                        [app.vesicles2,app.vesStats2] = thresholdvesicles(app.C3,app.cellMask,app.VesiclesSlider.Value,app.SizeEditField.Value,2);
                    end
                end
                app.showImage();
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Panel = uipanel(app.UIFigure);
            app.LoadimageButton = uibutton(app.Panel, 'state');
            app.LoadimageButton.ValueChangedFcn = createCallbackFcn(app, @loadImageButtonPushed, true);
            app.LoadimageButton.Text = 'Load image';
            app.Channel1CheckBox = uicheckbox(app.Panel);
            app.Channel1CheckBox.ValueChangedFcn = createCallbackFcn(app, @showImage, true);
            app.Channel1CheckBox.Text = 'Channel 1';
            app.Channel1CheckBox.Value = true;
            app.Channel2CheckBox = uicheckbox(app.Panel);
            app.Channel2CheckBox.ValueChangedFcn = createCallbackFcn(app, @showImage, true);
            app.Channel2CheckBox.Text = 'Channel 2';
            app.Channel2CheckBox.Value = true;
            app.Channel3CheckBox = uicheckbox(app.Panel);
            app.Channel3CheckBox.ValueChangedFcn = createCallbackFcn(app, @showImage, true);
            app.Channel3CheckBox.Text = 'Channel 3';
            app.Channel3CheckBox.Value = true;
            app.ColocalizationButton = uibutton(app.Panel, 'push');
            app.ColocalizationButton.ButtonPushedFcn = createCallbackFcn(app, @getColocalizationButtonPushed, true);
            app.ColocalizationButton.Text = 'Co-localization';
            app.ColocalizationDropDown1 = uidropdown(app.Panel);
            app.ColocalizationDropDown1.Items = {'Channel 1', 'Channel 2', 'Channel 3'};
            app.ColocalizationDropDown1.Value = 'Channel 1';
            app.ColocalizationDropDown2 = uidropdown(app.Panel);
            app.ColocalizationDropDown2.Items = {'Channel 1', 'Channel 2', 'Channel 3'};
            app.ColocalizationDropDown2.Value = 'Channel 1';
            app.CellMaskCheckBox = uicheckbox(app.Panel);
            app.CellMaskCheckBox.ValueChangedFcn = createCallbackFcn(app, @showImage, true);
            app.CellMaskCheckBox.Text = 'Cell Mask';
            app.SegmentDropDown = uidropdown(app.Panel);
            app.SegmentDropDown.Items = {'Channel 1', 'Channel 2', 'Channel 3'};
            app.SegmentDropDown.Value = 'Channel 1';
            app.SegmentCellLabel = uilabel(app.Panel);
            app.SegmentCellLabel.Text = 'Segment Cell';
            app.LoadMaskButton = uibutton(app.Panel, 'push');
            app.LoadMaskButton.ButtonPushedFcn = createCallbackFcn(app, @loadMaskButtonPushed, true);
            app.LoadMaskButton.Text = 'Load Mask';
            app.SegmentSlider = uislider(app.Panel);
            app.SegmentSlider.Limits = [0 1];
            app.SegmentSlider.MinorTicks = [];
            app.SegmentSlider.Value = 0.5;
            app.WatershedButton = uibutton(app.Panel, 'push');
            app.WatershedButton.ButtonPushedFcn = createCallbackFcn(app, @WatershedButtonPushed, true);
            app.WatershedButton.Text = 'Watershed';
            app.LoadImageLabel = uilabel(app.Panel);
            app.LoadImageLabel.Text = 'Load Image';
            app.AnalyzeLabel = uilabel(app.Panel);
            app.AnalyzeLabel.Text = 'Analyze';
            app.SegmentThresholdButton = uibutton(app.Panel, 'push');
            app.SegmentThresholdButton.ButtonPushedFcn = createCallbackFcn(app, @cellThresholdButtonPushed, true);
            app.SegmentThresholdButton.Text = 'Threshold';
            app.SaveLabel = uilabel(app.Panel);
            app.SaveLabel.Text = 'Save';
            app.SaveMaskButton = uibutton(app.Panel, 'push');
            app.SaveMaskButton.ButtonPushedFcn = createCallbackFcn(app, @SaveMaskButtonPushed, true);
            app.SaveMaskButton.Text = 'Save Mask';
            app.SaveDataButton = uibutton(app.Panel, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
            app.LevelLabel2 = uilabel(app.Panel);
            app.LevelLabel2.Text = 'Level';
            app.ApproximateButton = uibutton(app.Panel, 'push');
            app.ApproximateButton.ButtonPushedFcn = createCallbackFcn(app, @ApproximateButtonPushed, true);
            app.ApproximateButton.Text = 'Approximate';
            app.VesiclesDropDown2 = uidropdown(app.Panel);
            app.VesiclesDropDown2.Items = {'Vesicles 1', 'Vesicles 2'};
            app.VesiclesDropDown2.Value = 'Vesicles 1';
            app.ThresholdVesicles = uibutton(app.Panel, 'push');
            app.ThresholdVesicles.ButtonPushedFcn = createCallbackFcn(app, @thresholdVesiclesButtonPushed, true);
            app.ThresholdVesicles.Text = 'Threshold';
            app.VesiclesDropDown = uidropdown(app.Panel);
            app.VesiclesDropDown.Items = {'Channel 1', 'Channel 2', 'Channel 3'};
            app.VesiclesDropDown.Value = 'Channel 1';
            app.VesiclesLabel = uilabel(app.Panel);
            app.VesiclesLabel.Text = 'Vesicles';
            app.VesiclesSlider = uislider(app.Panel);
            app.VesiclesSlider.Limits = [0 1];
            app.VesiclesSlider.MinorTicks = [];
            app.VesiclesSlider.Value = 0.5;
            app.LevelLabel = uilabel(app.Panel);
            app.LevelLabel.Text = 'Level';
            app.Vesicles1CheckBox = uicheckbox(app.Panel);
            app.Vesicles1CheckBox.ValueChangedFcn = createCallbackFcn(app, @showImage, true);
            app.Vesicles1CheckBox.Text = 'Vesicles 1';
            app.Vesicles2CheckBox = uicheckbox(app.Panel);
            app.Vesicles2CheckBox.ValueChangedFcn = createCallbackFcn(app, @showImage, true);
            app.Vesicles2CheckBox.Text = 'Vesicles 2';
            app.SizeEditFieldLabel = uilabel(app.Panel);
            app.SizeEditFieldLabel.Text = 'Size';
            app.SizeEditField = uieditfield(app.Panel, 'numeric');
            app.SegmentSigma = uislider(app.Panel);
            app.SegmentSigma.Limits = [0 10];
            app.SegmentSigma.MinorTicks = [];
            app.SegmentSigma.Value = 5;
            app.LevelLabel2_2 = uilabel(app.Panel);
            app.LevelLabel2_2.Text = 'Sigma';
            app.VesiclesSigma = uislider(app.Panel);
            app.VesiclesSigma.Limits = [0 5];
            app.VesiclesSigma.MinorTicks = [];
            app.VesiclesSigma.Value = 2.5;
            app.SigmaLabel = uilabel(app.Panel);
            app.SigmaLabel.Text = 'Sigma';
            app.SegmentRadius = uislider(app.Panel);
            app.SegmentRadius.Limits = [0 1];
            app.SegmentRadius.MinorTicks = [];
            app.SegmentRadius.Value = 0.5;
            app.LevelLabel2_3 = uilabel(app.Panel);
            app.LevelLabel2_3.Text = 'Radius';
            app.UIAxes = uiaxes(app.UIFigure);
            app.ZSliderLabel = uilabel(app.UIFigure);
            app.ZSliderLabel.Text = 'Z';
            app.ZSlider = uislider(app.UIFigure);
            app.ZSlider.MajorTicks = [];
            app.ZSlider.MajorTickLabels = {};
            app.ZSlider.ValueChangedFcn = createCallbackFcn(app, @showImage, true);
            app.ZSlider.ValueChangingFcn = createCallbackFcn(app, @showImage, true);
            app.ZSlider.MinorTicks = [];
        end
    end

    methods (Access = public)

        % Construct app
        function app = ImageAnalyzer

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @initialize)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end